classdef fingerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AppLayout                       matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        SetupTabLeftRightSplitLayout    matlab.ui.container.GridLayout
        SetupRightLayout                matlab.ui.container.GridLayout
        FingerEETargetPathLabel         matlab.ui.control.Label
        FingerEETargetPathLayout        matlab.ui.container.GridLayout
        XYAxes                          matlab.ui.control.UIAxes
        XZAxes                          matlab.ui.control.UIAxes
        HomeConfiguration               matlab.ui.control.UIAxes
        SetupLeftLayout                 matlab.ui.container.GridLayout
        SolverSettingsLabel             matlab.ui.control.Label
        SolverSettingsLayout            matlab.ui.container.GridLayout
        NumberofiterationsEditField     matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        SolveInverseKinematicsDropDown  matlab.ui.control.DropDown
        SolveInverseKinematicsDropDownLabel  matlab.ui.control.Label
        TableTop                        matlab.ui.container.GridLayout
        PlannedPathofEndEffectorLabel   matlab.ui.control.Label
        DelBtn                          matlab.ui.control.Button
        OpenBtn                         matlab.ui.control.Button
        SaveBtn                         matlab.ui.control.Button
        AddBtn                          matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        DimensionofphalangesLabel       matlab.ui.control.Label
        LinkDefinitionLayout            matlab.ui.container.GridLayout
        DistalSpinner                   matlab.ui.control.Spinner
        DistalSpinnerLabel              matlab.ui.control.Label
        MiddleSpinner                   matlab.ui.control.Spinner
        MiddleSpinnerLabel              matlab.ui.control.Label
        ProximalSpinner                 matlab.ui.control.Spinner
        ProximalSpinnerLabel            matlab.ui.control.Label
        EETable                         matlab.ui.control.Table
        RunAndShareTab                  matlab.ui.container.Tab
        RunAndShareTabLeftRightSplitLayout  matlab.ui.container.GridLayout
        RunNShareRightLayout            matlab.ui.container.GridLayout
        StepSliderLayout                matlab.ui.container.GridLayout
        StepSlider                      matlab.ui.control.Slider
        StepSliderLabel                 matlab.ui.control.Label
        ResultConfiguration             matlab.ui.control.UIAxes
        RunNShareLeftLayout             matlab.ui.container.GridLayout
        ShareButton                     matlab.ui.control.Button
        ResultsTable                    matlab.ui.control.Table
        ResultsofInverseKinematicsLabel  matlab.ui.control.Label
    end


    methods (Access = private)
        
        function drawFinger(app, ax, jointAngles)
            base = [0, 0, 0]; % Base position at the origin
            lengths = [app.ProximalSpinner.Value app.MiddleSpinner.Value app.DistalSpinner.Value];

            % Get the positions of each joint
            [joint1, joint2, joint3, fingertip] = GUI_get_all_positions(lengths, jointAngles);
       
            cla(ax);
            hold(ax, 'on');
            plot3(ax, [base(1), joint1(1)], [base(2), joint1(2)], [base(3), joint1(3)], 'k', 'LineWidth', 2);
            plot3(ax, [joint1(1), joint2(1)], [joint1(2), joint2(2)], [joint1(3), joint2(3)], 'k', 'LineWidth', 2);
            plot3(ax, [joint2(1), joint3(1)], [joint2(2), joint3(2)], [joint2(3), joint3(3)], 'k', 'LineWidth', 2);
            plot3(ax, [joint3(1), fingertip(1)], [joint3(2), fingertip(2)], [joint3(3), fingertip(3)], 'k', 'LineWidth', 2);

            % Plot joints with different colors
            plot3(ax, joint1(1), joint1(2), joint1(3), 'ro', 'MarkerFaceColor', 'r');
            plot3(ax, joint2(1), joint2(2), joint2(3), 'bo', 'MarkerFaceColor', 'b');
            plot3(ax, joint3(1), joint3(2), joint3(3), 'co', 'MarkerFaceColor', 'c');
            plot3(ax, fingertip(1), fingertip(2), fingertip(3), 'go', 'MarkerFaceColor', 'g');
        
        end
        
        function updateEEPlannedPathPlot(app)
            cla(app.XYAxes);
            cla(app.XZAxes);
            data = app.EETable.Data;
            % Extract X, Y, Z columns
            X = data(:, 2);
            Y = data(:, 3);
            Z = data(:, 4);
            X = vertcat(X{:});
            Y = vertcat(Y{:});
            Z = vertcat(Z{:});
            nrows = size(data, 1);
            if nrows <= 1
                % Plot points
                plot3(app.XYAxes, X, Y, Z, 'go', 'MarkerFaceColor', 'g');
                plot3(app.XZAxes, X, Y, Z, 'go', 'MarkerFaceColor', 'g');
                view(app.XYAxes, 0, 90);
                view(app.XZAxes, 0, 0);
                return;
            end
            
            hold(app.XYAxes, 'on');
            hold(app.XZAxes, 'on');
            
            % Plot lines between points
            for i = 1:length(X)-1
                plot3(app.XYAxes, [X(i) X(i+1)], [Y(i) Y(i+1)], [Z(i) Z(i+1)], 'k', 'LineWidth', 1.5);
                plot3(app.XZAxes, [X(i) X(i+1)], [Y(i) Y(i+1)], [Z(i) Z(i+1)], 'k', 'LineWidth', 1.5);
            end
           
            % Plot points
            plot3(app.XYAxes, X, Y, Z, 'go', 'MarkerFaceColor', 'g');
            plot3(app.XZAxes, X, Y, Z, 'go', 'MarkerFaceColor', 'g');
            for i=1:nrows
                text(app.XYAxes, X(i), Y(i), Z(i), num2str(i), 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
                text(app.XZAxes, X(i), Y(i), Z(i), num2str(i), 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
            end
            view(app.XYAxes, 0, 90);
            view(app.XZAxes, 0, 0);
            % Release the hold
            hold(app.XYAxes, 'off');
            hold(app.XZAxes, 'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Finger Model';
            app.UIFigure.WindowState = 'maximized';
            app.EETable.ColumnWidth = 'auto';
            x = app.ProximalSpinner.Value + app.MiddleSpinner.Value + app.DistalSpinner.Value;
            app.EETable.Data = {0 x 0 0 0};
            drawFinger(app, app.HomeConfiguration, [0 0 0 0 0]);
            updateEEPlannedPathPlot(app);
            app.NumberofiterationsEditField.Enable = "off";
        end

        % Value changed function: ProximalSpinner
        function L1ChangedFcn(app, event)
            drawFinger(app, app.HomeConfiguration, [0 0 0 0]);
            app.ResultsTable.Data = [];
            x = app.ProximalSpinner.Value + app.MiddleSpinner.Value + app.DistalSpinner.Value;
            app.EETable.Data = {0 x 0 0 0};
        end

        % Value changed function: MiddleSpinner
        function L2ChangedFcn(app, event)
            drawFinger(app, app.HomeConfiguration, [0 0 0 0]);
            app.ResultsTable.Data = [];
            x = app.ProximalSpinner.Value + app.MiddleSpinner.Value + app.DistalSpinner.Value;
            app.EETable.Data = {0 x 0 0 0};
        end

        % Value changed function: DistalSpinner
        function L3ChangedFcn(app, event)
            drawFinger(app, app.HomeConfiguration, [0 0 0 0]);
            app.ResultsTable.Data = [];
            x = app.ProximalSpinner.Value + app.MiddleSpinner.Value + app.DistalSpinner.Value;
            app.EETable.Data = {0 x 0 0 0};
        end

        % Button pushed function: OpenBtn
        function openFile(app, event)
            [file, path] = uigetfile('*.mat', 'Select File');
            
            if isequal(file, 0)
                return;
            end
            filePath = fullfile(path, file);
            loadedData = load(filePath);
            
            fieldNames = fieldnames(loadedData);
            if numel(fieldNames) == 1
                dataTable = loadedData.(fieldNames{1});
            else
                error('The .mat file contains multiple variables. Please specify which one to load.');
            end
            
            % Check if the loaded data is indeed a table
            if ~istable(dataTable)
                error('The loaded data is not a table.');
            end
            
            app.EETable.Data = dataTable;
            app.ResultsTable.Data = [];
        end

        % Button pushed function: AddBtn
        function addRow(app, event)
            data = app.EETable.Data;
            if isempty(data)
                tValue = 0; % Start of tRange
            else
                tValue = data{end, 1} + 1; % Last time value plus 1
            end
        
            % Add a new row with the time value and all other values as 0
            newRow = {tValue, 0, 0, 0, 0};
            data = [data; newRow];
            app.EETable.Data = data;
            updateEEPlannedPathPlot(app);
            app.ResultsTable.Data = [];
        end

        % Button pushed function: DelBtn
        function removeRow(app, event)
            data = app.EETable.Data;
            if isempty(data)
                return;
            end
        
            % Get the selected
            selected = app.EETable.Selection;
            if isempty(selected)
                return;
            end
            
            rows = selected(:, 1);
            % dont delete first row
            rows = rows(rows ~= 1);
            rows = unique(rows);

            % Remove the selected rows
            data(rows, :) = [];
            app.EETable.Data = data;
            updateEEPlannedPathPlot(app);
            app.ResultsTable.Data = [];
        end

        % Button pushed function: SaveBtn
        function saveFile(app, event)
            % Extract data and column names from the uitable
            data = app.EETable.Data;
            columnNames = app.EETable.ColumnName;
            
            % Convert the data and column names into a table
            dataTable = array2table(data, 'VariableNames', columnNames);
            
            [file, path] = uiputfile('*.mat', 'Save Table As');
            
            if isequal(file, 0)
                return;
            end
            
            filename = fullfile(path, file);
            save(filename, 'dataTable');
        end

        % Cell edit callback: EETable
        function OnCellEdit(app, event)
            x = app.ProximalSpinner.Value + app.MiddleSpinner.Value + app.DistalSpinner.Value;
            app.EETable.Data(1, :) = {0 x 0 0 0};
            updateEEPlannedPathPlot(app);
            app.ResultsTable.Data = [];
        end

        % Value changed function: SolveInverseKinematicsDropDown
        function SolverChanged(app, event)
            value = app.SolveInverseKinematicsDropDown.Value;
            if value == "Analytically"
                app.EETable.ColumnName = {'Step'; 'X(cm)'; 'Y(cm)'; 'Z(cm)'; 'Angle(rad)'};
                app.EETable.ColumnEditable = [true true true true true];
                app.NumberofiterationsEditField.Enable = "off";
            else
                app.EETable.ColumnName = {'Step'; 'X(cm)'; 'Y(cm)'; 'Z(cm)'; 'Angle(Disabled)'};
                app.EETable.ColumnEditable = [true true true true false];
                app.NumberofiterationsEditField.Enable = "on";
            end
            app.ResultsTable.Data = [];
        end

        % Button pushed function: RunButton
        function OnRunClicked(app, event)
            data = app.EETable.Data;
            nrows = size(data, 1);

            app.ResultsTable.Data = [];
            app.StepSlider.Value = 1;
            if nrows <= 1
               return;
            end
            X = data(:, 2);
            Y = data(:, 3);
            Z = data(:, 4);
            A = data(:, 5);
            X = vertcat(X{:});
            Y = vertcat(Y{:});
            Z = vertcat(Z{:});
            A = vertcat(A{:});

            proximal = app.ProximalSpinner.Value;
            middle = app.MiddleSpinner.Value;
            distal = app.DistalSpinner.Value;
            max_length = proximal + middle + distal;
            currentSolver = app.SolveInverseKinematicsDropDown.Value;
            steps = 1:nrows;
            results = NaN(nrows, 8);
            solveNumerically = strcmp(currentSolver, 'Numerically');
            invalidRows = [];
            if  solveNumerically
                model = RST_4DOF_finger_model(proximal/100, middle/100, distal/100, 15);
                model.setupFKSolver(double(app.NumberofiterationsEditField.Value));
                for i =steps
                    if sqrt(X(i)^2 + Y(i)^2 + Z(i)^2) > max_length
                        invalidRows = [invalidRows, i];
                        uialert(app.UIFigure, sprintf('The specified position for row %d is beyond the reachable space of the finger.', i), 'Input Error', 'Icon', 'warning');
                        continue;
                    end
                    model.moveFingerToPosition( ...
                        [X(i) / 100, Y(i) / 100, Z(i) / 100], false);
                    results(i, 1) = i;
                    results(i, 2:4) = model.getFingerEEPosition() * 100;
                    results(i, 5:8) = deg2rad(model.getFingerAngles());
                end
            else
                for i =steps

                    if sqrt(X(i)^2 + Y(i)^2 + Z(i)^2) > max_length
                        invalidRows = [invalidRows, i];
                        uialert(app.UIFigure, sprintf('The specified position for row %d is beyond the reachable space of the finger', i), 'Input Error', 'Icon', 'warning');
                        continue;
                    end

                    lengths = [proximal, middle, distal];
                    [theta1, theta2, theta3, theta4, angles_info] = GUI_inverse_kinematics( ...
                        lengths, X(i), Y(i), Z(i), A(i));
                    
                    invalid_angles = isnan([theta1, theta2, theta3, theta4]);
                    
                    if any(invalid_angles)
                        invalidRows = [invalidRows, i];
                        alert_msg = 'Invalid solution for the following angles:\n';
                        angle_names = {'Θ1 (MCPaa)', 'Θ2 (MCPfe)', 'Θ3 (PIP)', 'Θ4 (DIP)'};
                    
                        for j = 1:4
                            if invalid_angles(j)
                                alert_msg = sprintf('%s%s: %.6f (deg), Valid Range: [%.6f, %.6f] (deg)\n', ...
                                                    alert_msg, angle_names{j}, ...
                                                    rad2deg(angles_info(j).value), ...
                                                    rad2deg(angles_info(j).lb), ...
                                                    rad2deg(angles_info(j).ub));
                            end
                        end
                        
                        uialert(app.UIFigure, alert_msg, 'Solution Error', 'Icon', 'error');
                    end

                    results(i, 1) = i;
                    results(i, 2:4) = [X(i) Y(i) Z(i)];
                    results(i, 5:8) = [theta1 theta2 theta3 theta4];
                end
            end
            style_black = uistyle('FontColor', 'k');
            style_red = uistyle('FontColor', 'r');
            
            % Apply the default style to all rows
            for j = 1:nrows
                addStyle(app.ResultsTable, style_black, 'row', j);
            end
    
            % Apply the style to invalid rows
            for j = invalidRows
                addStyle(app.ResultsTable, style_red, 'row', j);
            end
            nanArray = isnan(results);
            nanArrayFromRow2 = nanArray(2:end, :);
            allNaNFromRow2 = all(all(nanArrayFromRow2));
            if ~allNaNFromRow2
                app.ResultsTable.Data = results;
                app.TabGroup.SelectedTab = app.RunAndShareTab;
                if results(1, 1) < results(nrows, 1)
                    app.StepSlider.Limits = [results(1, 1), results(nrows, 1)];
                else
                    app.StepSlider.Limits = [1 2];
                end
                app.StepSlider.MajorTicks = results(:, 1);
                app.StepSlider.MajorTickLabels = string(app.StepSlider.MajorTicks);
                app.StepSlider.MinorTicks = [];
                drawFinger(app, app.ResultConfiguration, results(1, 5:8));
            end
        end

        % Key release function: EETable
        function OnKeyReleaseFcn(app, event)
            key = event.Key;
            if strcmp(key, 'delete')
                removeRow(app, event);
            end
        end

        % Callback function
        function AddClicked(app, event)

        end

        % Value changed function: StepSlider
        function StepSliderChanged(app, event)
            step = app.StepSlider.Value;
            step = int32(round(step));
            results = app.ResultsTable.Data;
            angles = results(step, 5:8);
            angles = vertcat(angles);
            drawFinger(app, app.ResultConfiguration, angles);
        end

        % Button pushed function: ShareButton
        function OnShareClicked(app, event)
            data = app.ResultsTable.Data;
            nrows = size(data, 1);
            if nrows <= 1
               return;
            end
            mqClient = MQTT_finger_sender();
            for i = 1:nrows
                xyz = data(i, 2:4);
                angles = data(i, 5:8);
                xyz = vertcat(xyz);
                angles = vertcat(angles);
                mqClient.addToDataBatch(i, xyz, angles);
            end
            mqClient.sendDataBatch();
        end

        % Value changed function: NumberofiterationsEditField
        function OnNumberOfIterationsChanged(app, event)
            app.ResultsTable.Data = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AppLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.AppLayout);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Finger Setup';
            app.SetupTabLeftRightSplitLayout = uigridlayout(app.SetupTab);
            app.SetupLeftLayout = uigridlayout(app.SetupTabLeftRightSplitLayout);
            app.EETable = uitable(app.SetupLeftLayout);
            app.EETable.ColumnName = {'Step'; 'X(cm)'; 'Y(cm)'; 'Z(cm)'; 'Angle(rad)'};
            app.EETable.RowName = {};
            app.EETable.ColumnSortable = true;
            app.EETable.ColumnEditable = [true true true true true];
            app.EETable.CellEditCallback = createCallbackFcn(app, @OnCellEdit, true);
            app.EETable.KeyReleaseFcn = createCallbackFcn(app, @OnKeyReleaseFcn, true);
            app.LinkDefinitionLayout = uigridlayout(app.SetupLeftLayout);
            app.ProximalSpinnerLabel = uilabel(app.LinkDefinitionLayout);
            app.ProximalSpinnerLabel.Text = 'Proximal';
            app.ProximalSpinner = uispinner(app.LinkDefinitionLayout);
            app.ProximalSpinner.Step = 0.1;
            app.ProximalSpinner.Limits = [2.35 2.65];
            app.ProximalSpinner.ValueChangedFcn = createCallbackFcn(app, @L1ChangedFcn, true);
            app.ProximalSpinner.Value = 2.65;
            app.MiddleSpinnerLabel = uilabel(app.LinkDefinitionLayout);
            app.MiddleSpinnerLabel.Text = 'Middle';
            app.MiddleSpinner = uispinner(app.LinkDefinitionLayout);
            app.MiddleSpinner.Step = 0.1;
            app.MiddleSpinner.Limits = [2.24 2.37];
            app.MiddleSpinner.ValueChangedFcn = createCallbackFcn(app, @L2ChangedFcn, true);
            app.MiddleSpinner.Value = 2.37;
            app.DistalSpinnerLabel = uilabel(app.LinkDefinitionLayout);
            app.DistalSpinnerLabel.Text = 'Distal';
            app.DistalSpinner = uispinner(app.LinkDefinitionLayout);
            app.DistalSpinner.Step = 0.1;
            app.DistalSpinner.Limits = [2.2 2.32];
            app.DistalSpinner.ValueChangedFcn = createCallbackFcn(app, @L3ChangedFcn, true);
            app.DistalSpinner.Value = 2.32;
            app.DimensionofphalangesLabel = uilabel(app.SetupLeftLayout);
            app.DimensionofphalangesLabel.Text = 'Dimension of phalanges';
            app.RunButton = uibutton(app.SetupLeftLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @OnRunClicked, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'imgs', 'play.png');
            app.RunButton.Tooltip = {'Click Run to Solve Inverse Kinematics'};
            app.RunButton.Text = 'Run';
            app.TableTop = uigridlayout(app.SetupLeftLayout);
            app.AddBtn = uibutton(app.TableTop, 'push');
            app.AddBtn.ButtonPushedFcn = createCallbackFcn(app, @addRow, true);
            app.AddBtn.Icon = fullfile(pathToMLAPP, 'imgs', 'plus.png');
            app.AddBtn.Tooltip = {'Add Row'};
            app.AddBtn.Text = '';
            app.SaveBtn = uibutton(app.TableTop, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @saveFile, true);
            app.SaveBtn.Icon = fullfile(pathToMLAPP, 'imgs', 'diskette.png');
            app.SaveBtn.Tooltip = {'Save'};
            app.SaveBtn.Text = '';
            app.OpenBtn = uibutton(app.TableTop, 'push');
            app.OpenBtn.ButtonPushedFcn = createCallbackFcn(app, @openFile, true);
            app.OpenBtn.Icon = fullfile(pathToMLAPP, 'imgs', 'data-folder.png');
            app.OpenBtn.Tooltip = {'Load'};
            app.OpenBtn.Text = '';
            app.DelBtn = uibutton(app.TableTop, 'push');
            app.DelBtn.ButtonPushedFcn = createCallbackFcn(app, @removeRow, true);
            app.DelBtn.Icon = fullfile(pathToMLAPP, 'imgs', 'trash.png');
            app.DelBtn.Tooltip = {'Delete Selected Rows'};
            app.DelBtn.Text = '';
            app.PlannedPathofEndEffectorLabel = uilabel(app.TableTop);
            app.PlannedPathofEndEffectorLabel.Text = 'Planned Path of End-Effector';
            app.SolverSettingsLayout = uigridlayout(app.SetupLeftLayout);
            app.SolveInverseKinematicsDropDownLabel = uilabel(app.SolverSettingsLayout);
            app.SolveInverseKinematicsDropDownLabel.Text = 'Solve Inverse Kinematics';
            app.SolveInverseKinematicsDropDown = uidropdown(app.SolverSettingsLayout);
            app.SolveInverseKinematicsDropDown.Items = {'Analytically', 'Numerically'};
            app.SolveInverseKinematicsDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverChanged, true);
            app.SolveInverseKinematicsDropDown.Value = 'Analytically';
            app.NumberofiterationsEditFieldLabel = uilabel(app.SolverSettingsLayout);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.SolverSettingsLayout, 'numeric');
            app.NumberofiterationsEditField.Limits = [10 100];
            app.NumberofiterationsEditField.ValueChangedFcn = createCallbackFcn(app, @OnNumberOfIterationsChanged, true);
            app.NumberofiterationsEditField.Value = 10;
            app.SolverSettingsLabel = uilabel(app.SetupLeftLayout);
            app.SolverSettingsLabel.Text = 'Solver Settings';
            app.SetupRightLayout = uigridlayout(app.SetupTabLeftRightSplitLayout);
            app.HomeConfiguration = uiaxes(app.SetupRightLayout);
            title(app.HomeConfiguration, 'Finger Home Configuration')
            xlabel(app.HomeConfiguration, 'X')
            ylabel(app.HomeConfiguration, 'Y')
            zlabel(app.HomeConfiguration, 'Z')
            app.HomeConfiguration.XLim = [0 8];
            app.HomeConfiguration.YLim = [0 8];
            app.HomeConfiguration.ZLim = [0 8];
            app.FingerEETargetPathLayout = uigridlayout(app.SetupRightLayout);
            app.XZAxes = uiaxes(app.FingerEETargetPathLayout);
            xlabel(app.XZAxes, 'X')
            ylabel(app.XZAxes, 'Y')
            zlabel(app.XZAxes, 'Z')
            app.XZAxes.XLim = [-8 8];
            app.XZAxes.YLim = [-8 8];
            app.XZAxes.ZLim = [-8 8];
            app.XYAxes = uiaxes(app.FingerEETargetPathLayout);
            xlabel(app.XYAxes, 'X')
            ylabel(app.XYAxes, 'Y')
            zlabel(app.XYAxes, 'Z')
            app.XYAxes.XLim = [-8 8];
            app.XYAxes.YLim = [-8 8];
            app.XYAxes.ZLim = [-8 8];
            app.FingerEETargetPathLabel = uilabel(app.SetupRightLayout);
            app.FingerEETargetPathLabel.Text = 'Finger EE Target Path';
            app.RunAndShareTab = uitab(app.TabGroup);
            app.RunAndShareTab.Title = 'Review&Share';
            app.RunAndShareTabLeftRightSplitLayout = uigridlayout(app.RunAndShareTab);
            app.RunNShareLeftLayout = uigridlayout(app.RunAndShareTabLeftRightSplitLayout);
            app.ResultsofInverseKinematicsLabel = uilabel(app.RunNShareLeftLayout);
            app.ResultsofInverseKinematicsLabel.Text = 'Results of Inverse Kinematics';
            app.ResultsTable = uitable(app.RunNShareLeftLayout);
            app.ResultsTable.ColumnName = {'Step'; 'X(cm)'; 'Y(cm)'; 'Z(cm)'; 'Θ1(rad)'; 'Θ2(rad)'; 'Θ3(rad)'; 'Θ4(rad)'};
            app.ResultsTable.RowName = {};
            app.ShareButton = uibutton(app.RunNShareLeftLayout, 'push');
            app.ShareButton.ButtonPushedFcn = createCallbackFcn(app, @OnShareClicked, true);
            app.ShareButton.Icon = fullfile(pathToMLAPP, 'imgs', 'send.png');
            app.ShareButton.Text = 'Share';
            app.RunNShareRightLayout = uigridlayout(app.RunAndShareTabLeftRightSplitLayout);
            app.ResultConfiguration = uiaxes(app.RunNShareRightLayout);
            title(app.ResultConfiguration, 'Finger Path')
            xlabel(app.ResultConfiguration, 'X')
            ylabel(app.ResultConfiguration, 'Y')
            zlabel(app.ResultConfiguration, 'Z')
            app.ResultConfiguration.XLim = [-8 8];
            app.ResultConfiguration.YLim = [-8 8];
            app.ResultConfiguration.ZLim = [-8 8];
            app.StepSliderLayout = uigridlayout(app.RunNShareRightLayout);
            app.StepSliderLabel = uilabel(app.StepSliderLayout);
            app.StepSliderLabel.Text = 'Step';
            app.StepSlider = uislider(app.StepSliderLayout);
            app.StepSlider.Limits = [1 2];
            app.StepSlider.MajorTicks = [0 20 40 60 80 100];
            app.StepSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.StepSlider.ValueChangedFcn = createCallbackFcn(app, @StepSliderChanged, true);
            app.StepSlider.MinorTicks = [];
            app.StepSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fingerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end